classdef Step1GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImageNameEditFieldLabel       matlab.ui.control.Label
        ImageNameEditField            matlab.ui.control.EditField
        ProjectionAngleMinEditFieldLabel  matlab.ui.control.Label
        ProjectionAngleMinEditField   matlab.ui.control.NumericEditField
        ProjectionAngleMaxEditFieldLabel  matlab.ui.control.Label
        ProjectionAngleMaxEditField   matlab.ui.control.NumericEditField
        NumberofProjectionAngleStepsLabel  matlab.ui.control.Label
        NumberofProjectionAngleStepsEditField  matlab.ui.control.NumericEditField
        NumberOfSamplingPointsEditFieldLabel  matlab.ui.control.Label
        NumberOfSamplingPointsEditField  matlab.ui.control.NumericEditField
        DistanceEditFieldLabel        matlab.ui.control.Label
        DistanceEditField             matlab.ui.control.NumericEditField
        ProjectionatangleEditFieldLabel  matlab.ui.control.Label
        ProjectionatangleEditField    matlab.ui.control.NumericEditField
        ProjectButton                 matlab.ui.control.Button
        FilteredBackprojectionButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ImageNameEditField
        function ImageNameEditFieldValueChanged(app, event)
            value = app.ImageNameEditField.Value;
            
        end

        % Value changed function: ProjectionAngleMaxEditField
        function ProjectionAngleMaxEditFieldValueChanged(app, event)
            value = app.ProjectionAngleMaxEditField.Value;
            
        end

        % Value changed function: ProjectionAngleMinEditField
        function ProjectionAngleMinEditFieldValueChanged(app, event)
            value = app.ProjectionAngleMinEditField.Value;
            
        end

        % Value changed function: 
        % NumberofProjectionAngleStepsEditField
        function NumberofProjectionAngleStepsEditFieldValueChanged(app, event)
            value = app.NumberofProjectionAngleStepsEditField.Value;
            
        end

        % Value changed function: NumberOfSamplingPointsEditField
        function NumberOfSamplingPointsEditFieldValueChanged(app, event)
            value = app.NumberOfSamplingPointsEditField.Value;
            
        end

        % Value changed function: ProjectionatangleEditField
        function ProjectionatangleEditFieldValueChanged(app, event)
            value = app.ProjectionatangleEditField.Value;
            
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            value = app.DistanceEditField.Value;
            
        end

        % Button pushed function: ProjectButton
        function ProjectButtonPushed(app, event)
            %Muhammed Saadeddin Koçak 2232346

%Loading Image
imagename=app.ImageNameEditField.Value;
myimage=load(string(imagename));
C=struct2cell(myimage);%Cell
A=cell2mat(C);%Matrix
imagesc(app.UIAxes,A)
% A=[1 2;3 4];
%Getting Size
sizeofmatrix=size(A);
sizeofimage=sizeofmatrix(1,1);%Assuming image is square
%Getting beam number and step size
numberofbeams=app.NumberofProjectionAngleStepsEditField.Value;%Number of beams %Number of different angles where source located
stepsize=app.NumberOfSamplingPointsEditField.Value;%Number of steps %Number of angles gamma
projectionanglerange=[app.ProjectionAngleMinEditField.Value app.ProjectionAngleMaxEditField.Value];
beta=linspace(projectionanglerange(1),projectionanglerange(2)-(projectionanglerange(2)/numberofbeams),numberofbeams);
gamma=linspace(-90,90-(180/stepsize),stepsize);
D=app.DistanceEditField.Value;
%Defining t, teta, x, and y arrays according to beam number and step size
% t=D*sind(gamma(j));
% t=linspace(-sizeofimage*sqrt(2)/2,sizeofimage*sqrt(2)/2,numberofbeams);
% teta=linspace(0,180-(180/stepsize),stepsize);
% teta=beta(i)+gamma(j);
x=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);
y=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);
%Taking projections
 for i=1:(length(beta))
     for j=1:(length(gamma))
%Clearing dynamic memory
relevantmatrix=[];
rowdata=[]; columndata=[]; midpointx=[];
midpointy=[]; distance=[];
%Finding intersection points
    yfromx=(D*sind(gamma(j))/sind(beta(i)+gamma(j)))-(x.*cotd(beta(i)+gamma(j)));
    xfromy=(D*sind(gamma(j))/cosd(beta(i)+gamma(j)))-(y.*tand(beta(i)+gamma(j)));
%Detecting relevant x and y coordinates
m=1;
for g=1:(length(x))
    if (((-sizeofimage/2)<=yfromx(g))&&(yfromx(g)<=sizeofimage/2))
        relevantmatrix(m,1)=x(1,g);
        relevantmatrix(m,2)=yfromx(1,g);
        m=m+1;
    else
    end
end
for k=1:(length(y))
    if (((-sizeofimage/2)<=xfromy(k))&&(xfromy(k)<=sizeofimage/2))
        relevantmatrix(m,1)=xfromy(1,k);
        relevantmatrix(m,2)=y(1,k);
        m=m+1;
    else
    end
end
uniquerelevantmatrix=unique(relevantmatrix,'rows');%Deleting same x and y points
sortedrelevantmatrix=sortrows(uniquerelevantmatrix);%Sorting relevant x and y points
sizerelevant=size(uniquerelevantmatrix,1);%Detecting if there are relevant points
if sizerelevant==0 %If no relevant points exist return total attenuation
    totalattenuation=0;
    projectionmatrix(j,i)=totalattenuation;
else
[rowsizeofURM,columnsizeofURM]=size(uniquerelevantmatrix);
if (rowsizeofURM>1)%If there are more than one relevant point then calculate attenuation
%Calculating distance and midpoints to use them for detecting row and
%column data
for r=1:(rowsizeofURM-1)
    distance(r)=sqrt(((uniquerelevantmatrix(r,1)-uniquerelevantmatrix(r+1,1))^2)+((uniquerelevantmatrix(r,2)-uniquerelevantmatrix(r+1,2))^2));
    midpointx(r)=(uniquerelevantmatrix(r,1)+uniquerelevantmatrix(r+1,1))/2;
    midpointy(r)=(uniquerelevantmatrix(r,2)+uniquerelevantmatrix(r+1,2))/2;
end
%Finding corresponding column and row data
rowdata=(sizeofimage/2)-floor(midpointy);
columndata=(sizeofimage/2)+ceil(midpointx);
%Summing all attenuation
sizeofdistancematrix=size(distance);
sizeofdistancearray=sizeofdistancematrix(1,2);
totalattenuation=0;%Clearing dynamic data from previous calculation
for h=1:sizeofdistancearray
    totalattenuation=totalattenuation+(A(rowdata(1,h),columndata(1,h))*distance(1,h));
end
projectionmatrix(j,i)=totalattenuation;%Assigning attenuation to matrix
else%If there is only one relevant point beam is tangential, then attenuation is zero
    totalattenuation=0;
    projectionmatrix(j,i)=totalattenuation;
end
end
    end
 end

find(beta==app.ProjectionatangleEditField.Value)
 
fanbeam.projections=projectionmatrix;
fanbeam.beta_range=projectionanglerange;
fanbeam.beta_step=numberofbeams;
fanbeam.approach='equiangular';
fanbeam.proj_sampl=stepsize;
fanbeam.sizeofimage=sizeofimage;
fanbeam.D=D;
 %Projection at a single angle
 index=find(beta==app.ProjectionatangleEditField.Value);
 plot(app.UIAxes2,gamma,projectionmatrix(:,index(1)+1));

 imagesc(app.UIAxes3,projectionmatrix)
 save('fanbeam','fanbeam')
%  F=fanbeam(brain,D);
%  figure
%  plot(F(:,25))
        end

        % Button pushed function: FilteredBackprojectionButton
        function FilteredBackprojectionButtonPushed(app, event)
            %Muhammed Saadeddin Koçak 2232346

load("fanbeam.mat")

projectionmatrix=fanbeam.projections;
sizeofimage=fanbeam.sizeofimage;
D=fanbeam.D;
[stepsize numberofbeams]=size(projectionmatrix);
imagematrix=zeros(sizeofimage);


%Modifying Projection Matrix
for i=1:numberofbeams
    for j=1:stepsize
        modifiedprojectionmatrix(j,i)=projectionmatrix(j,i)*D*cosd((180/stepsize)*(j-(stepsize/2)));
    end
end
projectionmatrix=modifiedprojectionmatrix;

%Filtering
%Triangular Filter
triangfilter=triang(stepsize);%Designing filter in Fourier Domain

for o=1:numberofbeams
filteredprojectionmatrix(:,o)=ifft2((triangfilter).*(fft2(projectionmatrix(:,o))));
end
projectionmatrix=filteredprojectionmatrix;

%Defining beta, gamma, x, and y arrays according to beam number and step size
projectionanglerange=fanbeam.beta_range;
beta=linspace(projectionanglerange(1),projectionanglerange(2)-(projectionanglerange(2)/numberofbeams),numberofbeams);
gamma=linspace(-90,90-(180/stepsize),stepsize);

x=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);
y=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);

 for i=1:(length(beta))
     for j=1:(length(gamma))
%Clearing dynamic memory
relevantmatrix=[];
rowdata=[]; columndata=[]; midpointx=[];
midpointy=[]; distance=[];
%Finding intersection points
    yfromx=(D*sind(gamma(j))/sind(beta(i)+gamma(j)))-(x.*cotd(beta(i)+gamma(j)));
    xfromy=(D*sind(gamma(j))/cosd(beta(i)+gamma(j)))-(y.*tand(beta(i)+gamma(j)));
%Detecting relevant x and y coordinates
m=1;
for g=1:(length(x))
    if (((-sizeofimage/2)<=yfromx(g))&&(yfromx(g)<=sizeofimage/2))
        relevantmatrix(m,1)=x(1,g);
        relevantmatrix(m,2)=yfromx(1,g);
        m=m+1;
    else
    end
end
for k=1:(length(y))
    if (((-sizeofimage/2)<=xfromy(k))&&(xfromy(k)<=sizeofimage/2))
        relevantmatrix(m,1)=xfromy(1,k);
        relevantmatrix(m,2)=y(1,k);
        m=m+1;
    else
    end
end
uniquerelevantmatrix=unique(relevantmatrix,'rows');%Deleting same x and y points
sortedrelevantmatrix=sortrows(uniquerelevantmatrix);%Sorting relevant x and y points
sizerelevant=size(uniquerelevantmatrix,1);%Detecting if there are relevant points
if sizerelevant==0 %If no relevant points do nothing
else
[rowsizeofURM,columnsizeofURM]=size(uniquerelevantmatrix);
if (rowsizeofURM>1)%If there are more than one relevant point then calculate attenuation
%Calculating distance and midpoints to use them for detecting row and
%column data
for r=1:(rowsizeofURM-1)
    distance(r)=sqrt(((uniquerelevantmatrix(r,1)-uniquerelevantmatrix(r+1,1))^2)+((uniquerelevantmatrix(r,2)-uniquerelevantmatrix(r+1,2))^2));
    midpointx(r)=(uniquerelevantmatrix(r,1)+uniquerelevantmatrix(r+1,1))/2;
    midpointy(r)=(uniquerelevantmatrix(r,2)+uniquerelevantmatrix(r+1,2))/2;
end
%Finding corresponding column and row data
rowdata=(sizeofimage/2)-floor(midpointy);
columndata=(sizeofimage/2)+ceil(midpointx);
%Backprojecting one ray beam
sizeofdistancematrix=size(distance);
sizeofdistancearray=sizeofdistancematrix(1,2);
for h=1:sizeofdistancearray
    imagematrix(rowdata(1,h),columndata(1,h))=imagematrix(rowdata(1,h),columndata(1,h))+(projectionmatrix(j,i)*distance(1,h));
end
else%If there is only one relevant point beam is tangential, then do nothing
end
end
    end
 end
%Normalizing image matrix
imagematrix=real(imagematrix);
normimagematrix=(imagematrix-min(min(imagematrix)))/(max(max(imagematrix)));
%Displaying Image

imagesc(app.UIAxes4,normimagematrix);
delete('fanbeam.mat')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageNameEditFieldLabel = uilabel(app.UIFigure);
            app.ImageNameEditFieldLabel.Text = 'Image Name';
            app.ImageNameEditField = uieditfield(app.UIFigure, 'text');
            app.ImageNameEditField.ValueChangedFcn = createCallbackFcn(app, @ImageNameEditFieldValueChanged, true);
            app.ProjectionAngleMinEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectionAngleMinEditFieldLabel.Text = 'Projection Angle Min';
            app.ProjectionAngleMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectionAngleMinEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectionAngleMinEditFieldValueChanged, true);
            app.ProjectionAngleMaxEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectionAngleMaxEditFieldLabel.Text = 'Projection Angle Max';
            app.ProjectionAngleMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectionAngleMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectionAngleMaxEditFieldValueChanged, true);
            app.NumberofProjectionAngleStepsLabel = uilabel(app.UIFigure);
            app.NumberofProjectionAngleStepsLabel.Text = 'Number of Projection Angle Steps';
            app.NumberofProjectionAngleStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofProjectionAngleStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofProjectionAngleStepsEditFieldValueChanged, true);
            app.NumberOfSamplingPointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfSamplingPointsEditFieldLabel.Text = 'Number Of Sampling Points';
            app.NumberOfSamplingPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfSamplingPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfSamplingPointsEditFieldValueChanged, true);
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.ProjectionatangleEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectionatangleEditFieldLabel.Text = 'Projection at angle:';
            app.ProjectionatangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectionatangleEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectionatangleEditFieldValueChanged, true);
            app.ProjectButton = uibutton(app.UIFigure, 'push');
            app.ProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectButtonPushed, true);
            app.ProjectButton.Text = 'Project';
            app.FilteredBackprojectionButton = uibutton(app.UIFigure, 'push');
            app.FilteredBackprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @FilteredBackprojectionButtonPushed, true);
            app.FilteredBackprojectionButton.Text = 'Filtered Backprojection';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'My Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Projection')
            xlabel(app.UIAxes2, 'Gamma')
            ylabel(app.UIAxes2, 'Attenuation')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Sinogram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Backprojected Image')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Step1GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end